classdef TrackDataApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DataMenu              matlab.ui.container.Menu
        SelectStartPointMenu  matlab.ui.container.Menu
        Menu2                 matlab.ui.container.Menu
        TabGroup              matlab.ui.container.TabGroup
        EngineTab             matlab.ui.container.Tab
        RPMGaugeLabel         matlab.ui.control.Label
        RPMGauge              matlab.ui.control.Gauge
        ImportDataButton      matlab.ui.control.Button
        TestGraphsTab         matlab.ui.container.Tab
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
        LapDropDownLabel      matlab.ui.control.Label
        LapDropDown           matlab.ui.control.DropDown
    end


    properties (Access = public)
        Lat
        Long
        Speed
        Lat_filtered
        Long_filtered
        Speed_filtered
    end

    methods (Access = public)
    
        
        function results = Startup(app)
            %This function imports the data and then plots about 11k points
            %on the graph so the data can be seen. Next in the process is
            %to select a "start point" for the track in order to separate
            %the data lap by lap.
        data = uiimport('-file');
        app.Lat = rmmissing(data.x_Latitude___Degrees____180_0_180_0_50_');
        app.Long = rmmissing(data.x_Longitude___Degrees____180_0_180_0_50_');
        app.Speed = rmmissing(data.x_Speed___MPH___0_0_150_0_50_');
        app.Speed(numel(app.Long)) = 0;
        lat1 = 40.84479;
        lat2 = 40.84487;
        long1 = -96.76793;
        long2 = -96.76790;
        
        LapNumber = 1;
        counter = 0;
        index = 1;
        for i = 1:length(app.Lat)
           if (counter == 0) && ((app.Lat(i) <= lat2) && (app.Lat(i) >= lat1)) && ((app.Long(i) <= long2) && (app.Long(i) >= long1))
               index = 1;
               counter = 20;
               i = i + 1;
               LapNumber = LapNumber + 1;
           end
           app.Lat_filtered(index,LapNumber)= app.Lat(i);
           app.Long_filtered(index,LapNumber)= app.Long(i);
           app.Speed_filtered(index,LapNumber)= app.Speed(i);
           index = index + 1;
           if counter ~= 0
               counter = counter - 1;
           end
        end
        LapNumber
        
        colormap(app.UIAxes,hsv);
        scatter(app.UIAxes,app.Lat(10000:21000),app.Long(10000:21000),20,app.Speed(10000:21000));
        legend(app.UIAxes, "Speed");
                
        end
    end
    
    methods (Access = public)
        
%         function trackStartArea = SelectStartPoint(app,plot1)
%             trackStartArea = drawcuboid(plot1);
%             disp('This will print immediately');
%             uiwait(gcf); 
%             disp('This will print after you click Continue');
%             return;
%         end
    end
    
    methods (Access = public)
        
        function results = PlotTestGraphsTab(app,lap)
            scatter(app.UIAxes2,[1:1:length(nonzeros(app.Speed_filtered(:,lap)))],nonzeros(app.Speed_filtered(:,lap)));
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Size changed function: TestGraphsTab
        function TestGraphsTabSizeChanged(app, event)
            position = app.TestGraphsTab.Position;
            
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            %Once the importData button is pressed this function starts.
            Startup(app);
        end

        % Menu selected function: SelectStartPointMenu
        function SelectStartPointMenuSelected(app, event)
            %When Data -> Startpoint is selected this function is started.
%             startPointFigure = figure;
%             data = uiimport('-file');
%             Lat = rmmissing(data.x_Latitude___Degrees____180_0_180_0_50_');
%             Long = rmmissing(data.x_Longitude___Degrees____180_0_180_0_50_');
%             Speed = rmmissing(data.x_Speed___MPH___0_0_150_0_50_');
%             scatter(Lat(10000:21000),Long(10000:21000),20,Speed(10000:21000))
%             colormap(hsv);
            
        end

        % Value changed function: LapDropDown
        function LapDropDownValueChanged(app, event)
            value = str2num(app.LapDropDown.Value)
            scatter(app.UIAxes,nonzeros(app.Lat_filtered(:,value)),nonzeros((app.Long_filtered(:,value))));
            PlotTestGraphsTab(app,value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DataMenu = uimenu(app.UIFigure);
            app.DataMenu.Text = 'Data';
            app.SelectStartPointMenu = uimenu(app.DataMenu);
            app.SelectStartPointMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectStartPointMenuSelected, true);
            app.SelectStartPointMenu.Text = 'Select Start Point';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EngineTab = uitab(app.TabGroup);
            app.EngineTab.Title = 'Engine';
            app.RPMGaugeLabel = uilabel(app.EngineTab);
            app.RPMGaugeLabel.Text = 'RPM';
            app.RPMGauge = uigauge(app.EngineTab, 'circular');
            app.RPMGauge.Limits = [0 14];
            app.RPMGauge.ScaleDirection = 'counterclockwise';
            app.RPMGauge.Value = 1.4;
            app.ImportDataButton = uibutton(app.EngineTab, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.TestGraphsTab = uitab(app.TabGroup);
            app.TestGraphsTab.SizeChangedFcn = createCallbackFcn(app, @TestGraphsTabSizeChanged, true);
            app.TestGraphsTab.Title = 'Test Graphs';
            app.UIAxes2 = uiaxes(app.TestGraphsTab);
            title(app.UIAxes2, 'Track Speed')
            xlabel(app.UIAxes2, 'Point')
            ylabel(app.UIAxes2, 'Speed')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Track')
            xlabel(app.UIAxes, 'Lat')
            ylabel(app.UIAxes, 'Long')
            app.LapDropDownLabel = uilabel(app.UIFigure);
            app.LapDropDownLabel.Text = 'Lap';
            app.LapDropDown = uidropdown(app.UIFigure);
            app.LapDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.LapDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.LapDropDown.Editable = 'on';
            app.LapDropDown.ValueChangedFcn = createCallbackFcn(app, @LapDropDownValueChanged, true);
            app.LapDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrackDataApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end